classdef Comp_ContrBttn < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                     matlab.ui.container.TabGroup
        BraoTab                      matlab.ui.container.Tab
        ExportarDadosButton          matlab.ui.control.Button
        RobotTwinButton              matlab.ui.control.StateButton
        PCDropDown                   matlab.ui.control.DropDown
        PlanodeCoordDropDownLabel    matlab.ui.control.Label
        ControleDropDown             matlab.ui.control.DropDown
        ControleDropDownLabel        matlab.ui.control.Label
        Velo_Med                     matlab.ui.control.NumericEditField
        VelocidadeMdiaLabel          matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        IniciarButton                matlab.ui.control.StateButton
        AtivadoLamp                  matlab.ui.control.Lamp
        AtivadoLampLabel             matlab.ui.control.Label
        X_Plus                       matlab.ui.control.ToggleButton
        wZLabel                      matlab.ui.control.Label
        wYLabel                      matlab.ui.control.Label
        wXLabel                      matlab.ui.control.Label
        wZ_Minus                     matlab.ui.control.ToggleButton
        wY_Minus                     matlab.ui.control.ToggleButton
        wX_Minus                     matlab.ui.control.ToggleButton
        wZ_Plus                      matlab.ui.control.ToggleButton
        wY_Plus                      matlab.ui.control.ToggleButton
        wX_Plus                      matlab.ui.control.ToggleButton
        ZLabel                       matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        Z_Minus                      matlab.ui.control.ToggleButton
        Y_Minus                      matlab.ui.control.ToggleButton
        X_Minus                      matlab.ui.control.ToggleButton
        Z_Plus                       matlab.ui.control.ToggleButton
        Y_Plus                       matlab.ui.control.ToggleButton
        Parado                       matlab.ui.control.ToggleButton
        GarraTab                     matlab.ui.container.Tab
        ConSerLamp                   matlab.ui.control.Lamp
        ConexocomServoLabel          matlab.ui.control.Label
        AberturadaGarraSlider        matlab.ui.control.Slider
        AberturadaGarraSliderLabel   matlab.ui.control.Label
        DesconectarServoButton       matlab.ui.control.Button
        ConectarServoButton          matlab.ui.control.Button
        FechamentoTotalButton        matlab.ui.control.Button
        AbrerturaTotalButton         matlab.ui.control.Button
        JuntasTab                    matlab.ui.container.Tab
        Velo_Med_2                   matlab.ui.control.NumericEditField
        VelocidadeMdiaLabel_2        matlab.ui.control.Label
        ControleDropDown_2           matlab.ui.control.DropDown
        ControleDropDown_2Label      matlab.ui.control.Label
        MoverButton                  matlab.ui.control.Button
        CDJ6                         matlab.ui.control.Spinner
        Label_3                      matlab.ui.control.Label
        CDJ5                         matlab.ui.control.Spinner
        Label_2                      matlab.ui.control.Label
        CDJ4                         matlab.ui.control.Spinner
        Label                        matlab.ui.control.Label
        CDJ3                         matlab.ui.control.Spinner
        J3Label                      matlab.ui.control.Label
        CDJ2                         matlab.ui.control.Spinner
        J2Label                      matlab.ui.control.Label
        CDJ1                         matlab.ui.control.Spinner
        J1Label                      matlab.ui.control.Label
        ControleXboxTab              matlab.ui.container.Tab
        ExportarDadosButton_2        matlab.ui.control.Button
        ConSerLamp_2                 matlab.ui.control.Lamp
        ConexoServoLabel             matlab.ui.control.Label
        DesconectarServoButton_2     matlab.ui.control.Button
        ConectarServoButton_2        matlab.ui.control.Button
        PCDropDown_2                 matlab.ui.control.DropDown
        PlanodeCoordDropDownLabel_2  matlab.ui.control.Label
        ControleDropDown_3           matlab.ui.control.DropDown
        ControleDropDown_3Label      matlab.ui.control.Label
        PararButton                  matlab.ui.control.StateButton
        Panel                        matlab.ui.container.Panel
        SentidoField                 matlab.ui.control.EditField
        SentidoEditFieldLabel        matlab.ui.control.Label
        EixoField                    matlab.ui.control.EditField
        EixoEditFieldLabel           matlab.ui.control.Label
        MovimentoField               matlab.ui.control.EditField
        MovimentoEditFieldLabel      matlab.ui.control.Label
        TempoField                   matlab.ui.control.NumericEditField
        TempoEditFieldLabel          matlab.ui.control.Label
        IniciarButton_2              matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        RobotTwinEv
    end

    properties (Access = private)
        ESC_CHARACTER = 'e';
        port_num
    end
    
    properties (Access = private, Constant)
        ADDR_GOAL_POSITION = 116;
        ADDR_OPERATING_MODE = 11;
        ADDR_PRESENT_POSITION = 132;
        ADDR_TORQUE_ENABLE = 64;
        BAUDRATE = 57600;
        COMM_SUCCESS = 0;
        COMM_TX_FAIL = -1001;
        DEVICENAME = 'COM4';
        DXL_ID = 8;
        DXL_MAXIMUM_POSITION_VALUE = 4095*(90/360);
        DXL_MINIMUM_POSITION_VALUE = 0;
        DXL_MOVING_STATUS_THRESHOLD = 20;
        OPERATING_MODE = 3;
        PROTOCOL_VERSION = 2.0;
        TORQUE_DISABLE = 0;
        TORQUE_ENABLE = 1;
    end
    
    properties (Access = public)
        CommComau
        CoppComau
        Count = [1 1 1];
        Dir = [0 0 0];
        ExportarDados = 0;
        ExprtCount double = 1;
        ExprtPos
        ExprtTargPos
        ExprtTemp datetime
        GarraCheck = 0;
        GarraTam = 0;
        MovTipo = 0;
        P_ini
        q_Atual = [0 0 -pi/2 0 pi/2 0];
        q_Pos = [0 0 -pi/2 0 pi/2 0];
        q_PosIni = [0 0 -pi/2 0 pi/2 0];
        q_RobotTwin
    end
    
    
    methods (Access = public)
        
        function results = CrtlTrag(comp, dir)  
            % Definição das juntas
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            Tbase = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = Tbase;

            if comp.GarraCheck
                Ttool = SE3([1 0 0 0; 0 1 0 0; 0 0 1 comp.GarraTam; 0 0 0 1]);
                robot.tool = Ttool;
            end

            [R, Td] = tr2rt(robot.fkine(comp.q_PosIni));
            Rd = SO3(R);
            
            K = 1; % Define ganho
            
            %VelLim = 1;
            %VelMed = min(((comp.Velo_Med.Value + 50)*0.03), 8);
            VelMed = 0.5;
            Direcao = dir;
            
            tam = 0.005;
            
            % inicia variáveis para o desenho
            J = robot.jacob0(comp.q_PosIni); % Jacobiana geométrica
            T = robot.fkine(comp.q_PosIni); % Cinemática direta para pegar a pose do efetuador 
            buffer_p = transl(T);
            
            p_ini = transl(T);
            R = SO3(T); 
            R = R.R();
            
            if comp.PCDropDown.Value
                Direcao = R*Direcao';
            end

            xbase = p_ini(1) + Direcao(1)*VelMed*tam*comp.Count(1);
            ybase = p_ini(2) + Direcao(2)*VelMed*tam*comp.Count(2);
            zbase = p_ini(3) + Direcao(3)*VelMed*tam*comp.Count(3);
            
            if Direcao(1) ~= 0
                  comp.Count(1) = comp.Count(1) + 1;

            end

            if Direcao(2) ~= 0
                  comp.Count(2) = comp.Count(2) + 1;

            end

            if Direcao(3) ~= 0
                  comp.Count(3) = comp.Count(3) + 1;

            end

            xbaseD = Direcao(1)*VelMed;
            ybaseD = Direcao(2)*VelMed;
            zbaseD = Direcao(3)*VelMed;

            if (xbase^2 + ybase^2 + (zbase - 0.45)^2) > 1.2^2
                rsp = questdlg(["Alerta:" ; "Próximo ao Limite de Alcance"],"Alerta","Continue","Parada","Cancelar");
                
                switch rsp
                    case 'Continue'
                        results = 1;
                        return;
                    case 'Parada'
                        results = 1;
                        return;
                end
            end

            ptraj = [xbase; ybase; zbase];
            ptrajD = [xbaseD; ybaseD; zbaseD];
            
            
            e_ant = 1; % Erro anterior
            e = 0; % Erro atual
            err_dif = e - e_ant;

            while (norm(err_dif) > 10e-3)

                J = robot.jacob0(comp.q_Atual); % Jacobiana geométrica
                T = robot.fkine(comp.q_Atual); % Cinemática direta para pegar a pose do efetuador 
                p = transl(T); % Translação do efetuador
            
                R = SO3(T); 
                R = R.R(); % Extrai rotação do efetuador
                p_err = ptraj'-p; % Erro de translação
                nphi = rotm2axang2(Rd*R');
                nphi_err = nphi(1:3)*nphi(4); % Erro de rotação (n*phi)
                e_ant = e;
                e = [p_err'; nphi_err']; % Vetor de erro

                err_dif = e - e_ant;

                u = pinv(J(:, 1:end))*(K*(e + [ptrajD; 0; 0; 0])); % Lei de controle
                
                comp.q_Atual(1:end) = comp.q_Atual(1:end) + 0.005*u'; % Cálculo de theta (Regra do trapézio)
            end

            if comp.ControleDropDown.Value == 0

                comp.CoppComau.setJointTargetPosition(comp.q_Atual);

            else

                comp.CommComau.setJointPositionsFly(rad2deg(comp.q_Atual), true);

            end
            
            results = 0;


            if (rem( sum(comp.Count) , 10 ) == 1 && comp.RobotTwinButton.Value) || comp.ExportarDados
                
                if comp.ControleDropDown.Value == 1
                    
                    comp.ExprtCount
                    comp.q_RobotTwin = deg2rad(comp.CommComau.getJointPositions()');
                    comp.ExprtTemp(comp.ExprtCount) = datetime("now","Format","mm:ss.SSS");

                else
                    
                    comp.ExprtCount
                    comp.q_RobotTwin = comp.CoppComau.getJointPosition();
                    comp.ExprtTemp(comp.ExprtCount) = datetime("now","Format","mm:ss.SSS");

                end

                if comp.RobotTwinButton.Value

                    notify(comp, 'RobotTwinEv');                    

                end

                if comp.ExportarDados
                    comp.ExprtPos(:,comp.ExprtCount) = comp.q_RobotTwin;
                    comp.ExprtTargPos(:,comp.ExprtCount) = comp.q_Atual;
                    comp.ExprtCount = comp.ExprtCount + 1;
                end

            end

        end
        
        function results = CrtlOrit(comp, dir)
            % Definição das juntas
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            Tbase = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = Tbase;

            if comp.GarraCheck
                Ttool = SE3([1 0 0 0; 0 1 0 0; 0 0 1 comp.GarraTam; 0 0 0 1]);
                robot.tool = Ttool;
            end
            

%             if comp.ControleDropDown.Value == 0
% 
%                 comp.q_Atual = comp.q_Pos;
%             else
% 
%                 comp.q_Atual = deg2rad(comp.CommComau.getJointPositions()');
%             end

            [R, Td] = tr2rt(robot.fkine(comp.q_PosIni));
            Rd = SO3(R);
            
            K = 60; % Define ganho

            %VelLim = 1;
            VelMed = 180;
            Direcao = dir;
            
            tam = 0.005;
            
            % inicia variáveis para o desenho
            J = robot.jacob0(comp.q_PosIni); % Jacobiana geométrica
            T = robot.fkine(comp.q_PosIni); % Cinemática direta para pegar a pose do efetuador 
            buffer_p = transl(T);
            
            p_ini = transl(T);
            R = SO3(T); 
            
            if comp.PCDropDown.Value
                
                Direcao = round(R.R*Direcao',2);
                Direcao = round(Direcao'*R.R,2);
            else

                Direcao = round(Direcao*R.R,2);
            end


            if Direcao(1) ~= 0
                  ptraj = R*R.Rx(Direcao(1)*VelMed*tam*comp.Count(1),"deg");
                  comp.Count(1) = comp.Count(1) + 1;

            end

            if Direcao(2) ~= 0
                  ptraj = R*R.Ry(Direcao(2)*VelMed*tam*comp.Count(2),"deg");
                  comp.Count(2) = comp.Count(2) + 1;

            end
            
            if Direcao(3) ~= 0
                  ptraj = R*R.Rz(Direcao(3)*VelMed*tam*comp.Count(3),"deg");
                  comp.Count(3) = comp.Count(3) + 1;

            end

            xrotD = Direcao(1)*VelMed*(pi/180);
            yrotD = Direcao(2)*VelMed*(pi/180);
            zrotD = Direcao(3)*VelMed*(pi/180);


            ptrajD = [xrotD; yrotD; zrotD];
            ptrajD = R.R*ptrajD;
            p_ant = transl(T);
            
            hold on
            % Td.plot('rgb') % Plot pose desejada
            
            e_ant = 1; % Erro anterior
            e = 0; % Erro atual
            err_dif = e - e_ant;

            while (norm(err_dif) > 10e-3)
                J = robot.jacob0(comp.q_Atual); % Jacobiana geométrica
                T = robot.fkine(comp.q_Atual); % Cinemática direta para pegar a pose do efetuador 
                p = transl(T); % Translação do efetuador
            
                R = SO3(T); 
                R = R.R(); % Extrai rotação do efetuador
                p_err = p_ant-p; % Erro de translação
                nphi = rotm2axang2(ptraj.R*R');
                nphi_err = nphi(1:3)*nphi(4); % Erro de rotação (n*phi)
                e_ant = e;
                e = [p_err'; nphi_err']; % Vetor de erro

                err_dif = e - e_ant;

                u = pinv(J(:, 1:end))*(K*e + [0; 0; 0; ptrajD]); % Lei de controle
                
                comp.q_Atual(1:end) = comp.q_Atual(1:end) + 0.005*u'; % Cálculo de theta (Regra do trapézio)
            end

            if comp.ControleDropDown.Value == 0

                comp.CoppComau.setJointTargetPosition(comp.q_Atual);
                
            else

                comp.CommComau.setJointPositionsFly(rad2deg(comp.q_Atual), true);
            end
            
            results = 0;


            if (rem( sum(comp.Count) , 10 ) == 1 && comp.RobotTwinButton.Value) || comp.ExportarDados

                if comp.ControleDropDown.Value == 1

                    comp.q_RobotTwin = deg2rad(comp.CommComau.getJointPositions()');
                    comp.ExprtTemp(comp.ExprtCount) = datetime("now","Format","mm:ss.SSS");

                else
    
                    comp.q_RobotTwin = comp.CoppComau.getJointPosition();
                    comp.ExprtTemp(comp.ExprtCount) = datetime("now","Format","mm:ss.SSS");

                end

                if comp.RobotTwinButton.Value

                    notify(comp, 'RobotTwinEv');

                end

                if comp.ExportarDados
                    comp.ExprtPos(:,comp.ExprtCount) = comp.q_RobotTwin;
                    comp.ExprtTargPos(:,comp.ExprtCount) = comp.q_Atual;
                    comp.ExprtCount = comp.ExprtCount + 1;
                end

            end

        end
        
        function results = AberturaGarra(comp)
            packetHandler();

            dxl_goal_position = [comp.DXL_MINIMUM_POSITION_VALUE comp.DXL_MAXIMUM_POSITION_VALUE];         
    
            dxl_comm_result = comp.COMM_TX_FAIL;           % Communication result
            
            dxl_error = 0;                              % Dynamixel error
            dxl_present_position = 0;                   % Present position
            dxl_present_load = 0;                       %Present load
        
            dxl_present_position = read4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_PRESENT_POSITION);
                
            dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
            if dxl_comm_result ~= comp.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
            end

            % Write goal position
            if dxl_present_position <= dxl_goal_position(2)
                write4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_GOAL_POSITION, typecast(int32(dxl_present_position + 200), 'uint32'));
                dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
                dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
                if dxl_comm_result ~= comp.COMM_SUCCESS
                    fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
                elseif dxl_error ~= 0
                    fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
                end
            end
        
        end
        
        function results = FechamentoGarra(comp)
            packetHandler();

            dxl_goal_position = [comp.DXL_MINIMUM_POSITION_VALUE comp.DXL_MAXIMUM_POSITION_VALUE];         
    
            dxl_comm_result = comp.COMM_TX_FAIL;           % Communication result

            dxl_error = 0;                              % Dynamixel error
            dxl_present_position = 0;                   % Present position
            dxl_present_load = 0;                       %Present load
        
            dxl_present_position = read4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_PRESENT_POSITION);
                
            dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
            if dxl_comm_result ~= comp.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
            end

            % Write goal position
            if dxl_present_position >= dxl_goal_position(1)
                write4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_GOAL_POSITION, typecast(int32(dxl_present_position - 200), 'uint32'));
                dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
                dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
                if dxl_comm_result ~= comp.COMM_SUCCESS
                    fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
                elseif dxl_error ~= 0
                    fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IniciarButton
        function IniciarButtonValueChanged(comp, event)
            comp.Parado.Value = 1;
            comp.AtivadoLamp.Color = 'g';
            comp.IniciarButton.BackgroundColor = '0.00,0.85,0.00';
            comp.ControleDropDown.Enable = 0;

            dir_ant = [0 0 0];

            if comp.ControleDropDown.Value == 1

                comp.CommComau.setArmOverride(min(50, 0.5*comp.Velo_Med.Value));

            else

                comp.CoppComau.setJointTargetPosition(comp.q_Pos);
            end

            while comp.IniciarButton.Value ~= 0
                if comp.Parado.Value == 0
                    if ~isequal(dir_ant,comp.Dir)
                        
                        pause(0.1);
                        dir_ant = comp.Dir;
                    end
                    
                    if comp.MovTipo == 0
                        r = CrtlTrag(comp, comp.Dir);
    
                    else
                        r = CrtlOrit(comp, comp.Dir);
                        %pause(0.1);
                        
                    end

                    if r == 1 
                        break;
                    end

                end
                pause(0.05);
            end

            
            comp.q_Pos = [0 0 -pi/2 0 pi/2 0];

            comp.ControleDropDown.Enable = 1;
            comp.AtivadoLamp.Color = 'r';
            comp.IniciarButton.BackgroundColor = 'w';
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(comp, event)
            if comp.ControleDropDown.Value == 1 && comp.IniciarButton.Value == 1
                comp.q_PosIni = deg2rad(comp.CommComau.getJointPositions()');
                comp.q_Atual = comp.q_PosIni;
                comp.q_Pos = comp.q_Atual;

                comp.Count = [1 1 1];
            elseif comp.IniciarButton.Value == 1
                comp.q_PosIni = comp.CoppComau.getJointPosition();
                comp.q_Atual = comp.q_PosIni;
                comp.q_Pos = comp.q_Atual;

                comp.Count = [1 1 1];
            end
            
            if comp.X_Plus.Value == 1

                comp.Dir = [1 0 0];
                comp.MovTipo = 0;

            elseif comp.Y_Plus.Value == 1
                comp.Dir = [0 1 0];
                comp.MovTipo = 0;
            
            elseif comp.Z_Plus.Value == 1
                comp.Dir = [0 0 1];
                comp.MovTipo = 0;

            elseif comp.X_Minus.Value == 1
                comp.Dir = [-1 0 0];
                comp.MovTipo = 0;

            elseif comp.Y_Minus.Value == 1
                comp.Dir = [0 -1 0];
                comp.MovTipo = 0;

            elseif comp.Z_Minus.Value == 1 
                comp.Dir = [0 0 -1];  
                comp.MovTipo = 0;
        
            elseif  comp.wX_Plus.Value == 1
                comp.Dir = [1 0 0];
                comp.MovTipo = 1;

            elseif  comp.wY_Plus.Value == 1
                comp.Dir = [0 1 0];
                comp.MovTipo = 1;
            
            elseif comp.wZ_Plus.Value == 1
                comp.Dir = [0 0 1];
                comp.MovTipo = 1;

            elseif comp.wX_Minus.Value == 1
                comp.Dir = [-1 0 0];
                comp.MovTipo = 1;

            elseif comp.wY_Minus.Value == 1
                comp.Dir = [0 -1 0];
                comp.MovTipo = 1;

            elseif comp.wZ_Minus.Value == 1
                comp.Dir = [0 0 -1];
                comp.MovTipo = 1;

            end

            if comp.RobotTwinButton.Value
                
                if comp.ControleDropDown.Value == 1

                    comp.q_RobotTwin = deg2rad(comp.CommComau.getJointPositions()');

                else
    
                    comp.q_RobotTwin = comp.CoppComau.getJointPosition();
                end

                notify(comp, 'RobotTwinEv');

            end
            
        end

        % Button pushed function: AbrerturaTotalButton
        function AbrerturaTotalButtonPushed(comp, event)
        packetHandler();
    
        dxl_comm_result = comp.COMM_TX_FAIL;           % Communication result
        dxl_goal_position = [comp.DXL_MINIMUM_POSITION_VALUE comp.DXL_MAXIMUM_POSITION_VALUE];         % Goal position
        
        dxl_error = 0;                              % Dynamixel error
        dxl_present_position = 0;                   % Present position
        dxl_present_load = 0;                       %Present load
    
        
        % Write goal position
        write4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_GOAL_POSITION, typecast(int32(dxl_goal_position(2)), 'uint32'));
        dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
        dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
        if dxl_comm_result ~= comp.COMM_SUCCESS
            fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
        elseif dxl_error ~= 0
            fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
        end
    
        while 1
            % Read present position
            dxl_present_position = read4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_PRESENT_POSITION);
            dxl_present_load = read4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, 126);
            dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
            if dxl_comm_result ~= comp.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
            end
    
            fprintf('[ID:%03d] GoalPos:%03d  PresPos:%03d Current:%03d\n', comp.DXL_ID, dxl_goal_position(2), ...
                typecast(uint32(dxl_present_position), 'int32'), ...
                typecast(uint32(dxl_present_load), 'int32')); 

            if ~(abs(dxl_goal_position(2) - typecast(uint32(dxl_present_position), 'int32')) > comp.DXL_MOVING_STATUS_THRESHOLD)
                break;
            end

            pause(0.05);
        end
        

        end

        % Button pushed function: ConectarServoButton, 
        % ...and 1 other component
        function ConnectServoButtonPushed(comp, event)
        clc;
        
        lib_name = '';
        
        if strcmp(computer, 'PCWIN')
          lib_name = 'dxl_x86_c';
        elseif strcmp(computer, 'PCWIN64')
          lib_name = 'dxl_x64_c';
        elseif strcmp(computer, 'GLNX86')
          lib_name = 'libdxl_x86_c';
        elseif strcmp(computer, 'GLNXA64')
          lib_name = 'libdxl_x64_c';
        elseif strcmp(computer, 'MACI64')
          lib_name = 'libdxl_mac_c';
        end
        
        % Load Libraries
        if ~libisloaded(lib_name)
            [notfound, warnings] = loadlibrary(lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h');
        end
    
        comp.port_num = portHandler(comp.DEVICENAME);
        
        % Initialize PacketHandler Structs
        packetHandler();
        
        
        % Open port
        if (openPort(comp.port_num))
            fprintf('Succeeded to open the port!\n');
        else
            unloadlibrary(lib_name);
            fprintf('Failed to open the port!\n');
            input('Press any key to terminate...\n');
            return;
        end
        
        
        % Set port baudrate
        if (setBaudRate(comp.port_num, comp.BAUDRATE))
            fprintf('Succeeded to change the baudrate!\n');
        else
            unloadlibrary(lib_name);
            fprintf('Failed to change the baudrate!\n');
            input('Press any key to terminate...\n');
            return;
        end
        
        
        % Enable Dynamixel Torque
        write1ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_TORQUE_ENABLE, comp.TORQUE_ENABLE);
        dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
        dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
        if dxl_comm_result ~= comp.COMM_SUCCESS
            fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
        elseif dxl_error ~= 0
            fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
        else
            fprintf('Dynamixel has been successfully connected \n');

            comp.ConSerLamp.Color = 'g';
            comp.ConSerLamp_2.Color = 'g';
        end
        end

        % Button pushed function: FechamentoTotalButton
        function FechamentoTotalButtonPushed(comp, event)
        packetHandler();
    
        dxl_comm_result = comp.COMM_TX_FAIL;           % Communication result
        dxl_goal_position = [comp.DXL_MINIMUM_POSITION_VALUE comp.DXL_MAXIMUM_POSITION_VALUE];         % Goal position
        
        dxl_error = 0;                              % Dynamixel error
        dxl_present_position = 0;                   % Present position
        dxl_present_load = 0;                       %Present load


        % Write goal position
        write4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_GOAL_POSITION, typecast(int32(dxl_goal_position(1)), 'uint32'));
        dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
        dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
        if dxl_comm_result ~= comp.COMM_SUCCESS
            fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
        elseif dxl_error ~= 0
            fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
        end
    
        while 1
            % Read present position
            dxl_present_position = read4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_PRESENT_POSITION);
            dxl_present_load = read4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, 126);
            dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
            if dxl_comm_result ~= comp.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
            end
    
            fprintf('[ID:%03d] GoalPos:%03d  PresPos:%03d Current:%03d\n', comp.DXL_ID, dxl_goal_position(1), ...
                typecast(uint32(dxl_present_position), 'int32'), ...
                typecast(uint32(dxl_present_load), 'int32'));
    
            if ~(abs(dxl_goal_position(1) - typecast(uint32(dxl_present_position), 'int32')) > comp.DXL_MOVING_STATUS_THRESHOLD)
                break;
            end

            pause(0.05);
        end
        

        end

        % Button pushed function: DesconectarServoButton, 
        % ...and 1 other component
        function DisconnectServoButtonPushed(comp, event)
        packetHandler();
        
        lib_name = '';
            
        if strcmp(computer, 'PCWIN')
          lib_name = 'dxl_x86_c';
        elseif strcmp(computer, 'PCWIN64')
          lib_name = 'dxl_x64_c';
        elseif strcmp(computer, 'GLNX86')
          lib_name = 'libdxl_x86_c';
        elseif strcmp(computer, 'GLNXA64')
          lib_name = 'libdxl_x64_c';
        elseif strcmp(computer, 'MACI64')
          lib_name = 'libdxl_mac_c';
        end

        write1ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_TORQUE_ENABLE, comp.TORQUE_DISABLE);
        dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
        dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
        if dxl_comm_result ~= comp.COMM_SUCCESS
            fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
        elseif dxl_error ~= 0
            fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
        end
        
        % Close port
        closePort(comp.port_num);
        
        % Unload Library
        unloadlibrary(lib_name);

        comp.ConSerLamp.Color = 'r';
        comp.ConSerLamp_2.Color = 'r';
        
        close all;
        clear;
        end

        % Value changed function: AberturadaGarraSlider
        function AberturadaGarraSliderValueChanged(comp, event)
            value = (comp.AberturadaGarraSlider.Value/100)*comp.DXL_MAXIMUM_POSITION_VALUE;
            

            packetHandler();
    
            dxl_comm_result = comp.COMM_TX_FAIL;           % Communication result
            dxl_goal_position = value;         % Goal position
            
            dxl_error = 0;                              % Dynamixel error
            dxl_present_position = 0;                   % Present position
            dxl_present_load = 0;                       %Present load


            % Write goal position
            write4ByteTxRx(comp.port_num, comp.PROTOCOL_VERSION, comp.DXL_ID, comp.ADDR_GOAL_POSITION, typecast(int32(dxl_goal_position), 'uint32'));
            dxl_comm_result = getLastTxRxResult(comp.port_num, comp.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(comp.port_num, comp.PROTOCOL_VERSION);
            if dxl_comm_result ~= comp.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(comp.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(comp.PROTOCOL_VERSION, dxl_error));
            end
        end

        % Button pushed function: IniciarButton_2
        function IniciarButton_2Pushed(comp, event)
            addpath('./ControleX/');
            comp.TempoField.Editable = 0;
            
            controllerLibrary = NET.addAssembly([pwd '\ControleX\SharpDX.XInput.dll']);
            myController = SharpDX.XInput.Controller(SharpDX.XInput.UserIndex.One);
            
            VibrationLevel = SharpDX.XInput.Vibration;
            
            Left = zeros(100,1);
            Right = zeros(100,1);
            Time = zeros(100,1);

            dir_ant = [0 0 0];
            
            for i = 1:(comp.TempoField.Value*20)
                State = myController.GetState();
                ButtonStates = ButtonStateParser(State.Gamepad.Buttons); % Put this into a structure

                if ~isequal(dir_ant,comp.Dir) || isequal([0 0 0],comp.Dir)

                    if comp.ControleDropDown.Value == 1
                        comp.q_PosIni = deg2rad(comp.CommComau.getJointPositions()');
                        comp.q_Atual = comp.q_PosIni;
                        comp.q_Pos = comp.q_Atual;

                        comp.Count = [1 1 1];
                    else
                        comp.q_PosIni = comp.CoppComau.getJointPosition();
                        comp.q_Atual = comp.q_PosIni;
                        comp.q_Pos = comp.q_Atual;
                        
                        comp.Count = [1 1 1];
                    end
                        
                    pause(0.1);
                    dir_ant = comp.Dir;
                end

                if double(State.Gamepad.RightThumbY) >= 29000
                    comp.MovimentoField.Value = 'Translação';
                    comp.EixoField.Value = 'X';
                    comp.SentidoField.Value = '+ / Positivo';

                    comp.Dir = [1 0 0];
                    comp.MovTipo = 0;

                    CrtlTrag(comp, comp.Dir);

                elseif double(State.Gamepad.RightThumbX) >= 29000
                    comp.MovimentoField.Value = 'Translação';
                    comp.EixoField.Value = 'Y';
                    comp.SentidoField.Value = '+ / Positivo';

                    comp.Dir = [0 1 0];
                    comp.MovTipo = 0;

                    CrtlTrag(comp, comp.Dir);
                
                elseif double(ButtonStates.RightBumper) == 1
                    comp.MovimentoField.Value = 'Translação';
                    comp.EixoField.Value = 'Z';
                    comp.SentidoField.Value = '+ / Positivo';
                    
                    comp.Dir = [0 0 1];
                    comp.MovTipo = 0;

                    CrtlTrag(comp, comp.Dir);
    
                elseif double(State.Gamepad.RightThumbY) <= -29000
                    comp.MovimentoField.Value = 'Translação';
                    comp.EixoField.Value = 'X';
                    comp.SentidoField.Value = '- / Negativo';

                    comp.Dir = [-1 0 0];
                    comp.MovTipo = 0;

                    CrtlTrag(comp, comp.Dir);
    
                elseif double(State.Gamepad.RightThumbX) <= -29000
                    comp.MovimentoField.Value = 'Translação';
                    comp.EixoField.Value = 'Y';
                    comp.SentidoField.Value = '- / Negativo';
                    
                    comp.Dir = [0 -1 0];
                    comp.MovTipo = 0;

                    CrtlTrag(comp, comp.Dir);
    
                elseif double(State.Gamepad.RightTrigger) >= 200
                    comp.MovimentoField.Value = 'Translação';
                    comp.EixoField.Value = 'Z';
                    comp.SentidoField.Value = '- / Negativo';

                    comp.Dir = [0 0 -1];  
                    comp.MovTipo = 0;

                    CrtlTrag(comp, comp.Dir);
            
                elseif  double(State.Gamepad.LeftThumbY) >= 29000
                    comp.MovimentoField.Value = 'Orientação';
                    comp.EixoField.Value = 'X';
                    comp.SentidoField.Value = '+ / Positivo';

                    comp.Dir = [1 0 0];
                    comp.MovTipo = 1;

                    CrtlOrit(comp, comp.Dir);
    
                elseif  double(State.Gamepad.LeftThumbX) >= 29000
                    comp.MovimentoField.Value = 'Orientação';
                    comp.EixoField.Value = 'Y';
                    comp.SentidoField.Value = '+ / Positivo';


                    comp.Dir = [0 1 0];
                    comp.MovTipo = 1;

                    CrtlOrit(comp, comp.Dir);
                
                elseif double(ButtonStates.LeftBumper) == 1
                    comp.MovimentoField.Value = 'Orientação';
                    comp.EixoField.Value = 'Z';
                    comp.SentidoField.Value = '+ / Positivo';

                    comp.Dir = [0 0 1];
                    comp.MovTipo = 1;

                    CrtlOrit(comp, comp.Dir);
    
                elseif double(State.Gamepad.LeftThumbY) <= -29000
                    comp.MovimentoField.Value = 'Orientação';
                    comp.EixoField.Value = 'X';
                    comp.SentidoField.Value = '- / Negativo';

                    comp.Dir = [-1 0 0];
                    comp.MovTipo = 1;

                    CrtlOrit(comp, comp.Dir);
    
                elseif double(State.Gamepad.LeftThumbX) <= -29000
                    comp.MovimentoField.Value = 'Orientação';
                    comp.EixoField.Value = 'Y';
                    comp.SentidoField.Value = '- / Negativo';

                    comp.Dir = [0 -1 0];
                    comp.MovTipo = 1;

                    CrtlOrit(comp, comp.Dir);
    
                elseif double(State.Gamepad.LeftTrigger) >= 200
                    comp.MovimentoField.Value = 'Orientação';
                    comp.EixoField.Value = 'Z';
                    comp.SentidoField.Value = '- / Negativo';

                    comp.Dir = [0 0 -1];
                    comp.MovTipo = 1;

                    CrtlOrit(comp, comp.Dir);

                elseif double(ButtonStates.A) == 1
                    comp.MovimentoField.Value = 'Abre Garra';
                    comp.EixoField.Value = '-';
                    comp.SentidoField.Value = '-';

                    AberturaGarra(comp);

                    pause(0.01);

                elseif double(ButtonStates.B) == 1
                    comp.MovimentoField.Value = 'Fecha Garra';
                    comp.EixoField.Value = '-';
                    comp.SentidoField.Value = '-';

                    FechamentoGarra(comp);

                    pause(0.01);

                elseif double(ButtonStates.X) == 1
                    comp.MovimentoField.Value = 'Plano Base';
                    comp.EixoField.Value = '-';
                    comp.SentidoField.Value = '-';

                    comp.PCDropDown.Value = 0;
                    comp.PCDropDown_2.Value = 0;

                elseif double(ButtonStates.Y) == 1
                    comp.MovimentoField.Value = 'Plano Ferramenta';
                    comp.EixoField.Value = '-';
                    comp.SentidoField.Value = '-';

                    comp.PCDropDown.Value = 1;
                    comp.PCDropDown_2.Value = 1;

                else
                    comp.MovimentoField.Value = 'Parado';
                    comp.EixoField.Value = '-';
                    comp.SentidoField.Value = '-';

                    comp.Dir = [0 0 0];
                    comp.MovTipo = 1;
                    
                end

                if comp.PararButton.Value == 1
                    comp.PararButton.Value = 0;
                    break;

                end


%                 di = comp.Dir;
%                 t = comp.MovTipo
% 
%                 fprintf('%d\n%d\n', di, t);
                pause(.05);
            end    
            
            comp.Dir = [0 0 0];

            VibrationLevel.LeftMotorSpeed = 0;
            VibrationLevel.RightMotorSpeed = 0;
            myController.SetVibration(VibrationLevel); % If your controller supports vibration

            comp.TempoField.Editable = 1;
            comp.MovimentoField.Value = 'Parado';
            comp.EixoField.Value = '-';
            comp.SentidoField.Value = '-';

        end

        % Button pushed function: MoverButton
        function MoverButtonPushed(comp, event)
            if comp.ControleDropDown_2.Value == 0

                comp.CoppComau.setJointTargetPosition(deg2rad([comp.CDJ1.Value comp.CDJ2.Value comp.CDJ3.Value comp.CDJ4.Value comp.CDJ5.Value comp.CDJ6.Value]));

            else
                comp.CommComau.setArmOverride(min(50, 0.5*comp.Velo_Med_2.Value));
                comp.CommComau.setJointPositionsFly([comp.CDJ1.Value comp.CDJ2.Value comp.CDJ3.Value comp.CDJ4.Value comp.CDJ5.Value comp.CDJ6.Value], true);
            end

            comp.q_PosIni = deg2rad([comp.CDJ1.Value comp.CDJ2.Value comp.CDJ3.Value comp.CDJ4.Value comp.CDJ5.Value comp.CDJ6.Value]);
            comp.q_Atual = comp.q_PosIni;
            comp.q_Pos = comp.q_Atual;
            
        end

        % Value changed function: ControleDropDown
        function ControleDropDownValueChanged(comp, event)
            comp.ControleDropDown_3.Value = comp.ControleDropDown.Value;
            
        end

        % Value changed function: ControleDropDown_3
        function ControleDropDown_3ValueChanged(comp, event)
            comp.ControleDropDown.Value = comp.ControleDropDown_3.Value;
            
        end

        % Value changed function: PCDropDown_2
        function PCDropDown_2ValueChanged(comp, event)
            comp.PCDropDown.Value = comp.PCDropDown_2.Value;
            
        end

        % Value changed function: PCDropDown
        function PCDropDownValueChanged(comp, event)
            comp.PCDropDown_2.Value = comp.PCDropDown.Value;
            
        end

        % Button pushed function: ExportarDadosButton, 
        % ...and 1 other component
        function ExportarDadosButtonPushed(comp, event)
            if comp.ExportarDados == 1   
                assignin('base','ManimpTargtPos',comp.ExprtTargPos);
                assignin('base','ManimpPos',comp.ExprtPos);
                assignin('base','ManimpTemp',comp.ExprtTemp);
                assignin('base','Contag',comp.ExprtCount);

                comp.ExprtCount = 1;
               
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.CDJ1.Value = rad2deg(comp.q_Atual(1));
            comp.CDJ2.Value = rad2deg(comp.q_Atual(2));
            comp.CDJ3.Value = rad2deg(comp.q_Atual(3));
            comp.CDJ4.Value = rad2deg(comp.q_Atual(4));
            comp.CDJ5.Value = rad2deg(comp.q_Atual(5));
            comp.CDJ6.Value = rad2deg(comp.q_Atual(6));

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 348 276];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.BraoTab = uitab(comp.TabGroup);
            comp.BraoTab.Title = 'Braço';
            comp.ButtonGroup = uibuttongroup(comp.BraoTab);
            comp.ButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @ButtonGroupSelectionChanged, true);
            comp.Parado = uitogglebutton(comp.ButtonGroup);
            comp.Parado.Text = 'Parado';
            comp.Parado.Value = true;
            comp.Y_Plus = uitogglebutton(comp.ButtonGroup);
            comp.Y_Plus.Text = '+';
            comp.Z_Plus = uitogglebutton(comp.ButtonGroup);
            comp.Z_Plus.Text = '+';
            comp.X_Minus = uitogglebutton(comp.ButtonGroup);
            comp.X_Minus.Text = '-';
            comp.Y_Minus = uitogglebutton(comp.ButtonGroup);
            comp.Y_Minus.Text = '-';
            comp.Z_Minus = uitogglebutton(comp.ButtonGroup);
            comp.Z_Minus.Text = '-';
            comp.XLabel = uilabel(comp.ButtonGroup);
            comp.XLabel.Text = 'ΔX';
            comp.YLabel = uilabel(comp.ButtonGroup);
            comp.YLabel.Text = 'ΔY';
            comp.ZLabel = uilabel(comp.ButtonGroup);
            comp.ZLabel.Text = 'ΔZ';
            comp.wX_Plus = uitogglebutton(comp.ButtonGroup);
            comp.wX_Plus.Text = '+';
            comp.wY_Plus = uitogglebutton(comp.ButtonGroup);
            comp.wY_Plus.Text = '+';
            comp.wZ_Plus = uitogglebutton(comp.ButtonGroup);
            comp.wZ_Plus.Text = '+';
            comp.wX_Minus = uitogglebutton(comp.ButtonGroup);
            comp.wX_Minus.Text = '-';
            comp.wY_Minus = uitogglebutton(comp.ButtonGroup);
            comp.wY_Minus.Text = '-';
            comp.wZ_Minus = uitogglebutton(comp.ButtonGroup);
            comp.wZ_Minus.Text = '-';
            comp.wXLabel = uilabel(comp.ButtonGroup);
            comp.wXLabel.Text = 'ωX';
            comp.wYLabel = uilabel(comp.ButtonGroup);
            comp.wYLabel.Text = 'ωY';
            comp.wZLabel = uilabel(comp.ButtonGroup);
            comp.wZLabel.Text = 'ωZ';
            comp.X_Plus = uitogglebutton(comp.ButtonGroup);
            comp.X_Plus.Text = '+';
            comp.AtivadoLampLabel = uilabel(comp.ButtonGroup);
            comp.AtivadoLampLabel.Text = 'Ativado:';
            comp.AtivadoLamp = uilamp(comp.ButtonGroup);
            comp.IniciarButton = uibutton(comp.ButtonGroup, 'state');
            comp.IniciarButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IniciarButtonValueChanged, true);
            comp.IniciarButton.Text = 'Iniciar';
            comp.VelocidadeMdiaLabel = uilabel(comp.BraoTab);
            comp.VelocidadeMdiaLabel.Text = 'Velocidade [%]:';
            comp.Velo_Med = uieditfield(comp.BraoTab, 'numeric');
            comp.Velo_Med.Limits = [10 100];
            comp.Velo_Med.RoundFractionalValues = 'on';
            comp.Velo_Med.Value = 10;
            comp.ControleDropDownLabel = uilabel(comp.BraoTab);
            comp.ControleDropDownLabel.Text = 'Controle:';
            comp.ControleDropDown = uidropdown(comp.BraoTab);
            comp.ControleDropDown.Items = {'Coppelia', 'Commau'};
            comp.ControleDropDown.ItemsData = [0 1];
            comp.ControleDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ControleDropDownValueChanged, true);
            comp.ControleDropDown.Value = 0;
            comp.PlanodeCoordDropDownLabel = uilabel(comp.BraoTab);
            comp.PlanodeCoordDropDownLabel.Text = 'Plano de Coord.:';
            comp.PCDropDown = uidropdown(comp.BraoTab);
            comp.PCDropDown.Items = {'BASE', 'FERRAM.'};
            comp.PCDropDown.ItemsData = [0 1];
            comp.PCDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PCDropDownValueChanged, true);
            comp.PCDropDown.Value = 0;
            comp.RobotTwinButton = uibutton(comp.BraoTab, 'state');
            comp.RobotTwinButton.Text = 'Robot Twin';
            comp.ExportarDadosButton = uibutton(comp.BraoTab, 'push');
            comp.ExportarDadosButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExportarDadosButtonPushed, true);
            comp.ExportarDadosButton.Text = 'Exportar Dados';
            comp.GarraTab = uitab(comp.TabGroup);
            comp.GarraTab.Title = 'Garra';
            comp.AbrerturaTotalButton = uibutton(comp.GarraTab, 'push');
            comp.AbrerturaTotalButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AbrerturaTotalButtonPushed, true);
            comp.AbrerturaTotalButton.Text = 'Abrertura Total';
            comp.FechamentoTotalButton = uibutton(comp.GarraTab, 'push');
            comp.FechamentoTotalButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FechamentoTotalButtonPushed, true);
            comp.FechamentoTotalButton.Text = 'Fechamento Total';
            comp.ConectarServoButton = uibutton(comp.GarraTab, 'push');
            comp.ConectarServoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ConnectServoButtonPushed, true);
            comp.ConectarServoButton.Text = 'Conectar Servo';
            comp.DesconectarServoButton = uibutton(comp.GarraTab, 'push');
            comp.DesconectarServoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DisconnectServoButtonPushed, true);
            comp.DesconectarServoButton.Text = 'Desconectar Servo';
            comp.AberturadaGarraSliderLabel = uilabel(comp.GarraTab);
            comp.AberturadaGarraSliderLabel.Text = 'Abertura da Garra';
            comp.AberturadaGarraSlider = uislider(comp.GarraTab);
            comp.AberturadaGarraSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @AberturadaGarraSliderValueChanged, true);
            comp.ConexocomServoLabel = uilabel(comp.GarraTab);
            comp.ConexocomServoLabel.Text = 'Conexão com Servo:';
            comp.ConSerLamp = uilamp(comp.GarraTab);
            comp.JuntasTab = uitab(comp.TabGroup);
            comp.JuntasTab.Title = 'Juntas';
            comp.J1Label = uilabel(comp.JuntasTab);
            comp.J1Label.Text = 'θ1';
            comp.CDJ1 = uispinner(comp.JuntasTab);
            comp.CDJ1.Limits = [-360 360];
            comp.J2Label = uilabel(comp.JuntasTab);
            comp.J2Label.Text = 'θ2';
            comp.CDJ2 = uispinner(comp.JuntasTab);
            comp.CDJ2.Limits = [-360 360];
            comp.J3Label = uilabel(comp.JuntasTab);
            comp.J3Label.Text = 'θ3';
            comp.CDJ3 = uispinner(comp.JuntasTab);
            comp.CDJ3.Limits = [-360 360];
            comp.Label = uilabel(comp.JuntasTab);
            comp.Label.Text = 'θ4';
            comp.CDJ4 = uispinner(comp.JuntasTab);
            comp.CDJ4.Limits = [-360 360];
            comp.Label_2 = uilabel(comp.JuntasTab);
            comp.Label_2.Text = 'θ5';
            comp.CDJ5 = uispinner(comp.JuntasTab);
            comp.CDJ5.Limits = [-360 360];
            comp.Label_3 = uilabel(comp.JuntasTab);
            comp.Label_3.Text = 'θ6';
            comp.CDJ6 = uispinner(comp.JuntasTab);
            comp.CDJ6.Limits = [-360 360];
            comp.MoverButton = uibutton(comp.JuntasTab, 'push');
            comp.MoverButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MoverButtonPushed, true);
            comp.MoverButton.Text = 'Mover';
            comp.ControleDropDown_2Label = uilabel(comp.JuntasTab);
            comp.ControleDropDown_2Label.Text = 'Controle:';
            comp.ControleDropDown_2 = uidropdown(comp.JuntasTab);
            comp.ControleDropDown_2.Items = {'Coppelia', 'Commau'};
            comp.ControleDropDown_2.ItemsData = [0 1];
            comp.ControleDropDown_2.Value = 0;
            comp.VelocidadeMdiaLabel_2 = uilabel(comp.JuntasTab);
            comp.VelocidadeMdiaLabel_2.Text = 'Velocidade [%]:';
            comp.Velo_Med_2 = uieditfield(comp.JuntasTab, 'numeric');
            comp.Velo_Med_2.Limits = [10 100];
            comp.Velo_Med_2.RoundFractionalValues = 'on';
            comp.Velo_Med_2.Value = 10;
            comp.ControleXboxTab = uitab(comp.TabGroup);
            comp.ControleXboxTab.Title = 'Controle Xbox';
            comp.IniciarButton_2 = uibutton(comp.ControleXboxTab, 'push');
            comp.IniciarButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @IniciarButton_2Pushed, true);
            comp.IniciarButton_2.Text = 'Iniciar';
            comp.TempoEditFieldLabel = uilabel(comp.ControleXboxTab);
            comp.TempoEditFieldLabel.Text = 'Tempo';
            comp.TempoField = uieditfield(comp.ControleXboxTab, 'numeric');
            comp.TempoField.Limits = [0 360];
            comp.TempoField.Value = 60;
            comp.Panel = uipanel(comp.ControleXboxTab);
            comp.MovimentoEditFieldLabel = uilabel(comp.Panel);
            comp.MovimentoEditFieldLabel.Text = 'Movimento';
            comp.MovimentoField = uieditfield(comp.Panel, 'text');
            comp.MovimentoField.Editable = 'off';
            comp.EixoEditFieldLabel = uilabel(comp.Panel);
            comp.EixoEditFieldLabel.Text = 'Eixo';
            comp.EixoField = uieditfield(comp.Panel, 'text');
            comp.EixoField.Editable = 'off';
            comp.SentidoEditFieldLabel = uilabel(comp.Panel);
            comp.SentidoEditFieldLabel.Text = 'Sentido';
            comp.SentidoField = uieditfield(comp.Panel, 'text');
            comp.SentidoField.Editable = 'off';
            comp.PararButton = uibutton(comp.ControleXboxTab, 'state');
            comp.PararButton.Text = 'Parar';
            comp.ControleDropDown_3Label = uilabel(comp.ControleXboxTab);
            comp.ControleDropDown_3Label.Text = 'Controle:';
            comp.ControleDropDown_3 = uidropdown(comp.ControleXboxTab);
            comp.ControleDropDown_3.Items = {'Coppelia', 'Commau'};
            comp.ControleDropDown_3.ItemsData = [0 1];
            comp.ControleDropDown_3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ControleDropDown_3ValueChanged, true);
            comp.ControleDropDown_3.Value = 0;
            comp.PlanodeCoordDropDownLabel_2 = uilabel(comp.ControleXboxTab);
            comp.PlanodeCoordDropDownLabel_2.Text = 'Plano de Coord.:';
            comp.PCDropDown_2 = uidropdown(comp.ControleXboxTab);
            comp.PCDropDown_2.Items = {'BASE', 'FERRAM.'};
            comp.PCDropDown_2.ItemsData = [0 1];
            comp.PCDropDown_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PCDropDown_2ValueChanged, true);
            comp.PCDropDown_2.Value = 0;
            comp.ConectarServoButton_2 = uibutton(comp.ControleXboxTab, 'push');
            comp.ConectarServoButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ConnectServoButtonPushed, true);
            comp.ConectarServoButton_2.Text = 'Conectar Servo';
            comp.DesconectarServoButton_2 = uibutton(comp.ControleXboxTab, 'push');
            comp.DesconectarServoButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DisconnectServoButtonPushed, true);
            comp.DesconectarServoButton_2.Text = 'Desconectar Servo';
            comp.ConexoServoLabel = uilabel(comp.ControleXboxTab);
            comp.ConexoServoLabel.Text = 'Conexão Servo:';
            comp.ConSerLamp_2 = uilamp(comp.ControleXboxTab);
            comp.ExportarDadosButton_2 = uibutton(comp.ControleXboxTab, 'push');
            comp.ExportarDadosButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExportarDadosButtonPushed, true);
            comp.ExportarDadosButton_2.Text = 'Exportar Dados';
        end
    end
end