classdef Comp_Controle < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                     matlab.ui.container.TabGroup
        PosioTab                     matlab.ui.container.Tab
        Velo_Med                     matlab.ui.control.NumericEditField
        VelocidadeMdiaLabel          matlab.ui.control.Label
        Distancia                    matlab.ui.control.NumericEditField
        DistnciaLabel                matlab.ui.control.Label
        PCDropDown                   matlab.ui.control.DropDown
        PlanodeCoordDropDownLabel    matlab.ui.control.Label
        YMinus                       matlab.ui.control.Button
        YLabel                       matlab.ui.control.Label
        YPlus                        matlab.ui.control.Button
        XLabel                       matlab.ui.control.Label
        XMinus                       matlab.ui.control.Button
        XPlus                        matlab.ui.control.Button
        ZPlus                        matlab.ui.control.Button
        ZMinus                       matlab.ui.control.Button
        ZLabel                       matlab.ui.control.Label
        ControleLineardoManipuladorLabel  matlab.ui.control.Label
        LCCheckBox                   matlab.ui.control.CheckBox
        OrientaoTab                  matlab.ui.container.Tab
        PCDropDown_2                 matlab.ui.control.DropDown
        PlanodeCoordDropDownLabel_2  matlab.ui.control.Label
        Velo_Med_2                   matlab.ui.control.NumericEditField
        VelocidadeMdiaLabel_2        matlab.ui.control.Label
        Distancia_2                  matlab.ui.control.NumericEditField
        DistnciaLabel_2              matlab.ui.control.Label
        Theta5Minus                  matlab.ui.control.Button
        YLabel_2                     matlab.ui.control.Label
        Theta5Plus                   matlab.ui.control.Button
        XLabel_2                     matlab.ui.control.Label
        Theta4Minus                  matlab.ui.control.Button
        Theta4Plus                   matlab.ui.control.Button
        Theta6Plus                   matlab.ui.control.Button
        Theta6Minus                  matlab.ui.control.Button
        ZLabel_2                     matlab.ui.control.Label
        ControleAngulardoManipuladorLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        Seq_Movimento
    end

    properties (Access = public)
        Seq_LastOp double = 0;
        Seq_Size = 0;
        Vel = 100;
        q_PrevPos
        q_Seq double
        q_SeqIndOp
        q_Start = [pi/2, 0, -pi/2, 0, -pi/2, 0];
    end
    
    
    methods (Access = private)
        
        function results = CrtlTrag(comp, dir)
            % Definição das juntas
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            Tbase = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = Tbase;

            % Ponto inicial
            q_Atual = comp.q_Start;
            comp.q_PrevPos = q_Atual;
            comp.q_SeqIndOp = [];
            
            [R, Td] = tr2rt(robot.fkine(q_Atual));
            Rd = SO3(R);
            
            K = 1; % Define ganho
            ColorLine = comp.LCCheckBox.Value;
            
            %VelLim = 1;
            VelMed = comp.Velo_Med.Value/100;
            comp.Vel = VelMed;
            Dist = comp.Distancia.Value/100;
            Direcao = dir;
            
            tam = 0.005;
            tempFim = Dist/VelMed;
            tempo = (0:tam:tempFim);
            
            % inicia variáveis para o desenho
            J = robot.jacob0(q_Atual); % Jacobiana geométrica
            T = robot.fkine(q_Atual); % Cinemática direta para pegar a pose do efetuador 
            buffer_p = transl(T);
            
            p_ini = transl(T);
            R = SO3(T); 
            R = R.R();
            
            if comp.PCDropDown.Value
                Direcao = R*Direcao';
            end
            
            for j = 1:(length(tempo) - 1)
                xbase(j) = p_ini(1) + Direcao(1)*VelMed*tempo(j);
                ybase(j) = p_ini(2) + Direcao(2)*VelMed*tempo(j);
                zbase(j) = p_ini(3) + Direcao(3)*VelMed*tempo(j);
                xbaseD(j) = Direcao(1)*VelMed;
                ybaseD(j) = Direcao(2)*VelMed;
                zbaseD(j) = Direcao(3)*VelMed;

                if (xbase(j)^2 + ybase(j)^2 + (zbase(j) - 0.45)^2) > 1.3^2
                    rsp = questdlg(["Alerta:" ; "Próximo ao Limite de Alcance"],"Alerta","Continue","Parada","Cancelar");
                    
                    switch rsp
                        case 'Continue'
                            break
                        case 'Parada'
                            close(figure(1));
                            return;
                    end
                end
            end
            ptraj = [xbase; ybase; zbase];
            ptrajD = [xbaseD; ybaseD; zbaseD];
            
            hold on
            % Td.plot('rgb') % Plot pose desejada
            for i=1:length(ptraj)  % Critério de parada
            
                e_ant = 1; % Erro anterior
                e = 0; % Erro atual
                J = robot.jacob0(q_Atual); % Jacobiana geométrica
                T = robot.fkine(q_Atual); % Cinemática direta para pegar a pose do efetuador 
                p = transl(T); % Translação do efetuador
            
                % desenho do caminho feito pelo efetuador
                
%                 if ColorLine == true
%                 x = [buffer_p(:,1)' p(:,1)'];
%                 y = [buffer_p(:,2)' p(:,2)'];
%                 z = [buffer_p(:,3)' p(:,3)'];
%                 
%                 %plot3(x,y,z,'Color',[1 0 0],'LineWidth',2);
%                 buffer_p = p;
%                 end
            
                R = SO3(T); 
                R = R.R(); % Extrai rotação do efetuador
                p_err = ptraj(:,i)'-p; % Erro de translação
                nphi = rotm2axang2(Rd*R');
                nphi_err = nphi(1:3)*nphi(4); % Erro de rotação (n*phi)
                e_ant = e;
                e = [p_err'; nphi_err']; % Vetor de erro
                u = pinv(J(:, 1:end))*(K*(e + [ptrajD(:,i); 0; 0; 0])); % Lei de controle
                
                q_Atual(1:end) = q_Atual(1:end) + 0.005*u'; % Cálculo de theta (Regra do trapézio)
                comp.q_Seq(:,(comp.Seq_Size + i)) = q_Atual(1:end);
                comp.q_SeqIndOp(:,i) = q_Atual(1:end);

                %q_seq_rad = q';
                %q_seq = rad2deg(q_seq);
                
                control_sig(:,i) = u'; % Sinal de controle
                err(i) = norm(e); % Norma do erro
                norm(e)
            end
            comp.Seq_LastOp = comp.Seq_Size;
            comp.Seq_Size = comp.Seq_Size + length(ptraj);
            comp.q_Start = q_Atual;
            notify(comp, 'Seq_Movimento');

%             clf(figure(1));
%             robot.plot(q_Atual);
        end
        
        function results = CrtlAngl(comp, dir)
            
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            Tbase = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = Tbase;
            
            % Ponto inicial
            q_Atual = comp.q_Start;
            comp.q_PrevPos = q_Atual;
            comp.q_SeqIndOp = [];
            
            [R, Td] = tr2rt(robot.fkine(q_Atual));
            Rd = SO3(R);
            
            K = 1; % Define ganho
            
            %VelLim = 1;
            VelMed = comp.Velo_Med_2.Value;
            comp.Vel = VelMed;
            Dist = comp.Distancia_2.Value;
            Direcao = dir;
            
            tam = 0.005;
            tempFim = Dist/VelMed;
            tempo = (0:tam:tempFim);
            
            % inicia variáveis para o desenho
            J = robot.jacob0(q_Atual); % Jacobiana geométrica
            T = robot.fkine(q_Atual); % Cinemática direta para pegar a pose do efetuador 
            buffer_p = transl(T);
            
            p_ini = transl(T);
            R = SO3(T);
            Ro=R.R;
            
            if comp.PCDropDown_2.Value
                
                Direcao = round(R.R*Direcao',2);
                Direcao = round(Direcao'*R.R,2);
            else

                Direcao = round(Direcao*R.R,2);
            end


            for j = 1:(length(tempo) - 1)
                if Direcao(1) ~= 0
                      ptraj(j) = R*R.Rx(Direcao(1)*VelMed*tempo(j),"deg");

                elseif Direcao(2) ~= 0
                      ptraj(j) = R*R.Ry(Direcao(2)*VelMed*tempo(j),"deg");

                elseif Direcao(3) ~= 0
                      ptraj(j) = R*R.Rz(Direcao(3)*VelMed*tempo(j),"deg");

                end

                xrotD(j) = Direcao(1)*VelMed*(pi/180);
                yrotD(j) = Direcao(2)*VelMed*(pi/180);
                zrotD(j) = Direcao(3)*VelMed*(pi/180);
            end

            ptrajD = [xrotD; yrotD; zrotD];
            ptrajD = R.R*ptrajD;
            p_ant =transl(T);
            
            hold on
            % Td.plot('rgb') % Plot pose desejada
            for i=1:length(ptraj)  % Critério de parada
            
                e_ant = 1; % Erro anterior
                e = 0; % Erro atual
                J = robot.jacob0(q_Atual); % Jacobiana geométrica
                T = robot.fkine(q_Atual); % Cinemática direta para pegar a pose do efetuador 
                p = transl(T); % Translação do efetuador
            
                R = SO3(T); 
                R = R.R(); % Extrai rotação do efetuador
                p_err = p_ant-p; % Erro de translação
                nphi = rotm2axang2(ptraj(:,i).R*R');
                nphi_err = nphi(1:3)*nphi(4); % Erro de rotação (n*phi)
                e_ant = e;
                e = [p_err'; nphi_err']; % Vetor de erro
                u = pinv(J(:, 1:end))*(K*e + [0; 0; 0; ptrajD(:,i)]); % Lei de controle
                
                q_Atual(1:end) = q_Atual(1:end) + 0.005*u'; % Cálculo de theta (Regra do trapézio)
                comp.q_Seq(:,(comp.Seq_Size + i)) = q_Atual(1:end);
                comp.q_SeqIndOp(:,i) = q_Atual(1:end);

                %q_seq_rad = q';
                %q_seq = rad2deg(q_seq);
                
                control_sig(:,i) = u'; % Sinal de controle
                err(i) = norm(e); % Norma do erro
                norm(e)
            end
            comp.Seq_LastOp = comp.Seq_Size;
            comp.Seq_Size = comp.Seq_Size + length(ptraj);
            comp.q_Start = q_Atual;
            notify(comp, 'Seq_Movimento');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: XPlus
        function XPlusButtonPushed(comp, event)
            CrtlTrag(comp, [1 0 0]);
        end

        % Button pushed function: XMinus
        function XMinusButtonPushed(comp, event)
            CrtlTrag(comp, [-1 0 0]);
        end

        % Button pushed function: YPlus
        function YPlusButtonPushed(comp, event)
            CrtlTrag(comp, [0 1 0]);
        end

        % Button pushed function: YMinus
        function YMinusButtonPushed(comp, event)
            CrtlTrag(comp, [0 -1 0]);
        end

        % Button pushed function: ZPlus
        function ZPlusButtonPushed(comp, event)
            CrtlTrag(comp, [0 0 1]);
        end

        % Button pushed function: ZMinus
        function ZMinusButtonPushed(comp, event)
            CrtlTrag(comp, [0 0 -1]);
        end

        % Button pushed function: Theta4Plus
        function Theta4PlusButtonPushed(comp, event)
            CrtlAngl(comp,[1 0 0]);
        end

        % Button pushed function: Theta4Minus
        function Theta4MinusButtonPushed(comp, event)
            CrtlAngl(comp,[-1 0 0]);
        end

        % Button pushed function: Theta5Plus
        function Theta5PlusButtonPushed(comp, event)
            CrtlAngl(comp,[0 1 0]);
        end

        % Button pushed function: Theta5Minus
        function Theta5MinusButtonPushed(comp, event)
            CrtlAngl(comp,[0 -1 0]);
        end

        % Button pushed function: Theta6Plus
        function Theta6PlusButtonPushed(comp, event)
            CrtlAngl(comp,[0 0 1]);
        end

        % Button pushed function: Theta6Minus
        function Theta6MinusButtonPushed(comp, event)
            CrtlAngl(comp,[0 0 -1]);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 348 276];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.PosioTab = uitab(comp.TabGroup);
            comp.PosioTab.Title = 'Posição';
            comp.LCCheckBox = uicheckbox(comp.PosioTab);
            comp.LCCheckBox.Text = 'Linha de Caminho';
            comp.ControleLineardoManipuladorLabel = uilabel(comp.PosioTab);
            comp.ControleLineardoManipuladorLabel.Text = 'Controle Linear do Manipulador:';
            comp.ZLabel = uilabel(comp.PosioTab);
            comp.ZLabel.Text = 'ΔZ';
            comp.ZMinus = uibutton(comp.PosioTab, 'push');
            comp.ZMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZMinusButtonPushed, true);
            comp.ZMinus.Text = '-';
            comp.ZPlus = uibutton(comp.PosioTab, 'push');
            comp.ZPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZPlusButtonPushed, true);
            comp.ZPlus.Text = '+';
            comp.XPlus = uibutton(comp.PosioTab, 'push');
            comp.XPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @XPlusButtonPushed, true);
            comp.XPlus.Text = '+';
            comp.XMinus = uibutton(comp.PosioTab, 'push');
            comp.XMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @XMinusButtonPushed, true);
            comp.XMinus.Text = '-';
            comp.XLabel = uilabel(comp.PosioTab);
            comp.XLabel.Text = 'ΔX';
            comp.YPlus = uibutton(comp.PosioTab, 'push');
            comp.YPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @YPlusButtonPushed, true);
            comp.YPlus.Text = '+';
            comp.YLabel = uilabel(comp.PosioTab);
            comp.YLabel.Text = 'ΔY';
            comp.YMinus = uibutton(comp.PosioTab, 'push');
            comp.YMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @YMinusButtonPushed, true);
            comp.YMinus.Text = '-';
            comp.PlanodeCoordDropDownLabel = uilabel(comp.PosioTab);
            comp.PlanodeCoordDropDownLabel.Text = 'Plano de Coord.:';
            comp.PCDropDown = uidropdown(comp.PosioTab);
            comp.PCDropDown.Items = {'BASE', 'TOOL'};
            comp.PCDropDown.ItemsData = [0 1];
            comp.PCDropDown.Value = 0;
            comp.DistnciaLabel = uilabel(comp.PosioTab);
            comp.DistnciaLabel.Text = 'Distância Percorrida [cm]:';
            comp.Distancia = uieditfield(comp.PosioTab, 'numeric');
            comp.Distancia.Limits = [0 Inf];
            comp.Distancia.RoundFractionalValues = 'on';
            comp.Distancia.Value = 10;
            comp.VelocidadeMdiaLabel = uilabel(comp.PosioTab);
            comp.VelocidadeMdiaLabel.Text = 'Velocidade Média [cm/s]:';
            comp.Velo_Med = uieditfield(comp.PosioTab, 'numeric');
            comp.Velo_Med.Limits = [1 1000];
            comp.Velo_Med.RoundFractionalValues = 'on';
            comp.Velo_Med.Value = 100;
            comp.OrientaoTab = uitab(comp.TabGroup);
            comp.OrientaoTab.Title = 'Orientação';
            comp.ControleAngulardoManipuladorLabel = uilabel(comp.OrientaoTab);
            comp.ControleAngulardoManipuladorLabel.Text = 'Controle Angular do Manipulador:';
            comp.ZLabel_2 = uilabel(comp.OrientaoTab);
            comp.ZLabel_2.Text = 'ωZ';
            comp.Theta6Minus = uibutton(comp.OrientaoTab, 'push');
            comp.Theta6Minus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Theta6MinusButtonPushed, true);
            comp.Theta6Minus.Text = '-';
            comp.Theta6Plus = uibutton(comp.OrientaoTab, 'push');
            comp.Theta6Plus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Theta6PlusButtonPushed, true);
            comp.Theta6Plus.Text = '+';
            comp.Theta4Plus = uibutton(comp.OrientaoTab, 'push');
            comp.Theta4Plus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Theta4PlusButtonPushed, true);
            comp.Theta4Plus.Text = '+';
            comp.Theta4Minus = uibutton(comp.OrientaoTab, 'push');
            comp.Theta4Minus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Theta4MinusButtonPushed, true);
            comp.Theta4Minus.Text = '-';
            comp.XLabel_2 = uilabel(comp.OrientaoTab);
            comp.XLabel_2.Text = 'ωX';
            comp.Theta5Plus = uibutton(comp.OrientaoTab, 'push');
            comp.Theta5Plus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Theta5PlusButtonPushed, true);
            comp.Theta5Plus.Text = '+';
            comp.YLabel_2 = uilabel(comp.OrientaoTab);
            comp.YLabel_2.Text = 'ωY';
            comp.Theta5Minus = uibutton(comp.OrientaoTab, 'push');
            comp.Theta5Minus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Theta5MinusButtonPushed, true);
            comp.Theta5Minus.Text = '-';
            comp.DistnciaLabel_2 = uilabel(comp.OrientaoTab);
            comp.DistnciaLabel_2.Text = 'Distância Percorrida [°]:';
            comp.Distancia_2 = uieditfield(comp.OrientaoTab, 'numeric');
            comp.Distancia_2.Limits = [0 Inf];
            comp.Distancia_2.RoundFractionalValues = 'on';
            comp.Distancia_2.Value = 90;
            comp.VelocidadeMdiaLabel_2 = uilabel(comp.OrientaoTab);
            comp.VelocidadeMdiaLabel_2.Text = 'Velocidade Média [°/s]:';
            comp.Velo_Med_2 = uieditfield(comp.OrientaoTab, 'numeric');
            comp.Velo_Med_2.Limits = [1 180];
            comp.Velo_Med_2.RoundFractionalValues = 'on';
            comp.Velo_Med_2.Value = 1;
            comp.PlanodeCoordDropDownLabel_2 = uilabel(comp.OrientaoTab);
            comp.PlanodeCoordDropDownLabel_2.Text = 'Plano de Coord.:';
            comp.PCDropDown_2 = uidropdown(comp.OrientaoTab);
            comp.PCDropDown_2.Items = {'BASE', 'TOOL'};
            comp.PCDropDown_2.ItemsData = [0 1];
            comp.PCDropDown_2.Value = 0;
        end
    end
end